classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DistancingKnow  matlab.ui.Figure
        Label           matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
        VideoButton     matlab.ui.control.Button
        Button          matlab.ui.control.Button
        Label_2         matlab.ui.control.Label
        KeepyourcommunitysafefromCOVID19Label  matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Label_5         matlab.ui.control.Label
        Label_6         matlab.ui.control.Label
        ImageButton     matlab.ui.control.Button
        Label_7         matlab.ui.control.Label
        Label_8         matlab.ui.control.Label
        Label_9         matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.UIAxes)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Close request function: DistancingKnow
        function DistancingKnowCloseRequest(app, event)
            %delete(app);
            cla(app.UIAxes)
        end

        % Callback function
        function ImageButtonPushed(app, event)
            cla(app.UIAxes)
            
            global image;
            % Image Selection
            [filename,pathname] = uigetfile(fullfile(pwd,'Images','*.*'),'Select an Image')
            fname = [filename]
            filewithpath = strcat(pathname,filename);
            
            image = imread(filewithpath);
            
            % Create detector variable & boxes for people detected in the image
            detector = peopleDetectorACF();
            [bboxes,scores] = detect(detector,image);
            
            % Check for all boxes and compare distances
            cond = zeros(size(bboxes,1),1);
            if ~isempty(bboxes)
                for i=1:(size(bboxes,1)-1)
                    for j=(i+1):(size(bboxes,1)-1)
                         dis1_v = abs(bboxes(i,1)+bboxes(i,3)-bboxes(j,1));
                         dis2_v = abs(bboxes(j,1)+bboxes(j,3)-bboxes(i,1));
                         dis1_h = abs(bboxes(i,2)-bboxes(j,2));
                         dis2_h = abs(bboxes(i,2)+bboxes(i,4)-bboxes(j,2)-bboxes(j,4));
                         if((dis1_v<75 || dis2_v<75) && (dis1_h<50 || dis2_h<50))
                            cond(i)=cond(i)+1;
                            cond(j)=cond(j)+1;
                         else
                            cond(i)=cond(i)+0; 
                         end
                    end
                end
            end
            image = insertObjectAnnotation(image,'rectangle',bboxes((cond>0),:),'unsafe','color','r');
            image = insertObjectAnnotation(image,'rectangle',bboxes((cond==0),:),'safe','color','g');
             
            % Display image detection
            imshow(image, 'Parent', app.UIAxes);
            
            % Save image
            filename = strcat(fname,' (SD_Detection).jpg');
            imwrite(image,filename);


        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            cla(app.UIAxes)
                        
            % Read video file
            [filename,pathname] = uigetfile(fullfile(pwd,'Videos','*.*'),'Select a Video Clip')
            vidfile=strcat(pathname,filename);
            
            video = vision.VideoFileReader(vidfile);
            videoPlayer = vision.VideoPlayer('Position',[300 100 880 500]);
                        
            % Create detector variable
            detector = peopleDetectorACF('caltech-50x21');
            
            % Writes (new) detection video (to save output)
            writerObj = VideoWriter('Video Detection Output.avi');
            writerObj.FrameRate = 8;    % Frames Per Second
            
            % Starts writing video 
            open(writerObj);
            
            while ~isDone(video)
                load_img = imread('loading_screen.png');
                imshow(load_img,'Parent',app.UIAxes);
            
                frame = step(video);  % Get frame
                I=double(frame);
                [bboxes,scores] = detect(detector,I);
                
                cond = zeros(size(bboxes,1),1);
                if ~isempty(bboxes)
                    for i=1:(size(bboxes,1)-1)
                        for j=(i+1):(size(bboxes,1)-1)
                             dis1_v = abs(bboxes(i,1)+bboxes(i,3)-bboxes(j,1));
                             dis2_v = abs(bboxes(j,1)+bboxes(j,3)-bboxes(i,1));
                             dis1_h = abs(bboxes(i,2)-bboxes(j,2));
                             dis2_h = abs(bboxes(i,2)+bboxes(i,4)-bboxes(j,2)-bboxes(j,4));
                             if((dis1_v<75 || dis2_v<75) && (dis1_h<50 || dis2_h<50))
                                cond(i)=cond(i)+1;
                                cond(j)=cond(j)+1;
                             else
                                cond(i)=cond(i)+0; 
                             end
                        end
                    end
                end
                I = insertObjectAnnotation(I,'rectangle',bboxes((cond>0),:),'unsafe','color','r');
                I = insertObjectAnnotation(I,'rectangle',bboxes((cond==0),:),'safe','color','g');
                    
                step(videoPlayer,I); 
                frame = im2frame(I);
                writeVideo(writerObj,frame);                
            end
            
            % Display Video Detection (new window)
            release(video);
            release(videoPlayer);
                        
            % Close Video
            close(writerObj);
            
            cla(app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DistancingKnow = uifigure;
            app.DistancingKnow.Name = 'Social Distance Tracking / Monitoring System';
            app.DistancingKnow.CloseRequestFcn = createCallbackFcn(app, @DistancingKnowCloseRequest, true);
            app.Label = uilabel(app.DistancingKnow);
            app.Label.Text = '👥';
            app.UIAxes = uiaxes(app.DistancingKnow);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.VideoButton = uibutton(app.DistancingKnow, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.Button = uibutton(app.DistancingKnow, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '↻';
            app.Label_2 = uilabel(app.DistancingKnow);
            app.Label_2.Text = 'Distancing Know';
            app.KeepyourcommunitysafefromCOVID19Label = uilabel(app.DistancingKnow);
            app.KeepyourcommunitysafefromCOVID19Label.Text = 'Keep your community safe from COVID-19.';
            app.Label_4 = uilabel(app.DistancingKnow);
            app.Label_4.Text = '-';
            app.Label_5 = uilabel(app.DistancingKnow);
            app.Label_5.Text = '-';
            app.Label_6 = uilabel(app.DistancingKnow);
            app.Label_6.Text = '-';
            app.ImageButton = uibutton(app.DistancingKnow, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.Label_7 = uilabel(app.DistancingKnow);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.DistancingKnow);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.DistancingKnow);
            app.Label_9.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DistancingKnow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DistancingKnow)
        end
    end
end